classdef Copy_of_App_for_OISL_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Datasource1Label_2              matlab.ui.control.Label
        Defaultvaluesbasedon2AdjustablebyuserLabel  matlab.ui.control.Label
        Datasource1Label                matlab.ui.control.Label
        Image5_9                        matlab.ui.control.Image
        ResultsDerivedfromGivenParametersLabel  matlab.ui.control.Label
        Image5_5                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        LinkParametersLabel             matlab.ui.control.Label
        SatelliteTerminalParametersLabel  matlab.ui.control.Label
        ReceiverRXLabel                 matlab.ui.control.Label
        TransmitterTXLabel              matlab.ui.control.Label
        BEREditField                    matlab.ui.control.NumericEditField
        BEREditFieldLabel               matlab.ui.control.Label
        ReqPrxdBmEditField              matlab.ui.control.NumericEditField
        RequiredPrxLabel                matlab.ui.control.Label
        BERCurveFittingButton           matlab.ui.control.Button
        SatDistkmEditField              matlab.ui.control.NumericEditField
        SatDistkmEditFieldLabel         matlab.ui.control.Label
        DataRateGbsEditField            matlab.ui.control.NumericEditField
        DataRateGbsEditFieldLabel       matlab.ui.control.Label
        LinkMargindBEditField           matlab.ui.control.NumericEditField
        LinkMargindBEditFieldLabel      matlab.ui.control.Label
        ReceivedPowerdBmEditField       matlab.ui.control.NumericEditField
        ReceivedPowerdBmEditFieldLabel  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        MatlabADSCadenceCosimulationPlatformLabel_2  matlab.ui.control.Label
        InterSatelliteOpticalLinkAnalyzerISOLALabel  matlab.ui.control.Label
        TransmittedPowerdBmEditField    matlab.ui.control.NumericEditField
        TransmittedPowerdBmEditFieldLabel  matlab.ui.control.Label
        SatADivAngleradEditField        matlab.ui.control.NumericEditField
        SatADivAngleradEditFieldLabel   matlab.ui.control.Label
        SatBPointingErrradEditField     matlab.ui.control.NumericEditField
        SatBPointingErrradEditFieldLabel  matlab.ui.control.Label
        SatBApertureDiametermEditField  matlab.ui.control.NumericEditField
        SatBApertureDiametermEditFieldLabel  matlab.ui.control.Label
        SatBOpticsEfficiencyEditField   matlab.ui.control.NumericEditField
        SatBOpticsEfficiencyEditFieldLabel  matlab.ui.control.Label
        SatAPointingErrradEditField     matlab.ui.control.NumericEditField
        SatAPointingErrradEditFieldLabel  matlab.ui.control.Label
        WavelengthmEditField            matlab.ui.control.NumericEditField
        WavelengthmEditFieldLabel       matlab.ui.control.Label
        ParametersvsDistancePlotsButton  matlab.ui.control.Button
        SatAOpticsEfficiencyEditField   matlab.ui.control.NumericEditField
        SatAOpticsEfficiencyEditFieldLabel  matlab.ui.control.Label
        BER_vs_Preq                     matlab.ui.control.UIAxes
        LM_vs_Distance                  matlab.ui.control.UIAxes
        Drate_vs_Distance               matlab.ui.control.UIAxes
        Prx_vs_Distance                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        SatAOpticsEfficiency double
        SatAPointingError double
        SatATxDivergenceAngle double

        SatBOpticsEfficiency double
        SatBApertureDiameter double
        SatBPointingError double
        
        Wavelength double
        
        SatelliteDistance double
        TransmittedPower double

        BitErrorRateBER double

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SatAOpticsEfficiency = app.SatAOpticsEfficiencyEditField.Value;
    app.SatAPointingError = app.SatAPointingErrradEditField.Value;
    app.SatATxDivergenceAngle = app.SatADivAngleradEditField.Value;

    app.SatBOpticsEfficiency = app.SatBOpticsEfficiencyEditField.Value;
    app.SatBApertureDiameter = app.SatBApertureDiametermEditField.Value;
    app.SatBPointingError = app.SatBPointingErrradEditField.Value;

    app.Wavelength = app.WavelengthmEditField.Value;

        end

        % Button pushed function: ParametersvsDistancePlotsButton
        function ParametersvsDistancePlotsButtonPushed(app, event)

    app.SatAOpticsEfficiency = app.SatAOpticsEfficiencyEditField.Value;
    app.SatAPointingError = app.SatAPointingErrradEditField.Value;
    app.SatATxDivergenceAngle = app.SatADivAngleradEditField.Value;

    app.SatBOpticsEfficiency = app.SatBOpticsEfficiencyEditField.Value;
    app.SatBApertureDiameter = app.SatBApertureDiametermEditField.Value;
    app.SatBPointingError = app.SatBPointingErrradEditField.Value;

    app.Wavelength = app.WavelengthmEditField.Value;

    app.TransmittedPower = app.TransmittedPowerdBmEditField.Value;

    app.SatelliteDistance = app.SatDistkmEditField.Value;
    app.BitErrorRateBER = app.BEREditField.Value;


    % Define the distance range between satellites in km, dss
    satDistances = linspace(300, 6000, 20);


% --------- Received Power versus Satellite Distance (Ptx=20dBm) -----------


% Set transmission power in dBm
Ptx = 20;

% Initial received power array
Prxs_20dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_20dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end


% ---------Received Power versus Satellite Distance (Ptx=25 dBm) -----------

% Set transmission power in dBm
Ptx = 25;

% Initial received power array
Prxs_25dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_25dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end


% ---------Received Power versus Satellite Distance (Ptx=30 dBm) -----------

% Set transmission power in dBm
Ptx = 30;

% Initial received power array
Prxs_30dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_30dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end


cla(app.Prx_vs_Distance); % 清除 BERAxes 上的现有内容
plot(app.Prx_vs_Distance, satDistances, Prxs_20dBm, '-s', 'LineWidth', 2,'Color','#789440','DisplayName','Ptx=20dBm(0.10W)');
hold(app.Prx_vs_Distance, 'on'); % 允许多次绘图
plot(app.Prx_vs_Distance, satDistances, Prxs_25dBm, '-o', 'LineWidth', 2,'Color','#7E649E','DisplayName','Ptx=25dBm(0.32W)');
plot(app.Prx_vs_Distance, satDistances, Prxs_30dBm, '-x', 'LineWidth', 2,'Color','#923931','DisplayName','Ptx=30dBm(1.00W)');

legend(app.Prx_vs_Distance ,'show');
%xlabel(app.Prx_vs_Distance, 'Distance between Satellites in km', 'FontSize', 14);
%ylabel(app.Prx_vs_Distance, 'Received Power in dBm', 'FontSize', 14);
%title(app.Prx_vs_Distance, 'Received Power vs Satellite Distance Plot', 'FontSize', 18);


grid (app.Prx_vs_Distance, 'on');
hold (app.Prx_vs_Distance, 'off');


%----------------------------------------------------------------------------------------------------------------------------------------------------


% --------- Received Power versus Satellite Distance (Ptx=20dBm) -----------


% Set transmission power in dBm
Ptx = 20;

% Initial received power array
Prxs_20dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_20dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end


% ---------Received Power versus Satellite Distance (Ptx=25 dBm) -----------

% Set transmission power in dBm
Ptx = 25;

% Initial received power array
Prxs_25dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_25dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end

% Received Power of the Specific Point @ 1000km
app.ReceivedPowerdBmEditField.Value = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - fspl(app.SatelliteDistance*1e3,app.Wavelength);


% ---------Received Power versus Satellite Distance (Ptx=30 dBm) -----------

% Set transmission power in dBm
Ptx = 30;

% Initial received power array
Prxs_30dBm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_30dBm(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;


end


cla(app.Prx_vs_Distance); % 清除 BERAxes 上的现有内容
plot(app.Prx_vs_Distance, satDistances, Prxs_20dBm, '-s', 'LineWidth', 2,'Color','#789440','DisplayName','Ptx=20dBm(0.10W)');
hold(app.Prx_vs_Distance, 'on'); % 允许多次绘图
plot(app.Prx_vs_Distance, satDistances, Prxs_25dBm, '-o', 'LineWidth', 2,'Color','#7E649E','DisplayName','Ptx=25dBm(0.32W)');
plot(app.Prx_vs_Distance, satDistances, Prxs_30dBm, '-x', 'LineWidth', 2,'Color','#923931','DisplayName','Ptx=30dBm(1.00W)');

legend(app.Prx_vs_Distance ,'show');


grid (app.Prx_vs_Distance, 'on');
hold (app.Prx_vs_Distance, 'off');

%----------------------------------------------------------------------------------------------------------------------------------------------------------


% OOK data point
power_ook = [-50, -49.1, -48.1, -47, -46, -45, -44, -43, -42, -41, -40, -39, -38, -37];
log_ber_ook = [-1.15, -1.35, -1.6, -1.9, -2.15, -2.7, -3, -3.8, -4.55, -5.25, -6.25, -7.6, -8.8, -10];

% 3-rd order curve fitting
p_ook_3th = polyfit(power_ook, log_ber_ook, 3);

% generate curve
fitted_log_ber_ook_3th = polyval(p_ook_3th, power_ook);

% from log scale to linear BER
fitted_ber_ook_3th = 10.^fitted_log_ber_ook_3th;

% from log scale to linear BER
ber_ook = 10.^log_ber_ook;

% Optical Inter-Satellite Communication Link Budget Analysis
% Optical Inter-Satellite Link Model


% Configure the satellites and link characteristics

% Set the satellite A characteristics with parabolic telescope

% satellite A to satellite B

%Ptx = 25;   % Transmitted power in dBm

% This example considers the receiver sensitivity as —35.5 dBm for on-off keying modulation 
% with a 10 Gbps data rate and 1e-12 BER, as defined in [6], so Preq is defined as


% -------- IV. Link Margin versus Satellite Distance (Changing BER)-----------------------------
% Under the condition of:
% ---------IV.1 Link Margin versus Satellite Distance (1e-12 BER) -----------

% Set required receiver power for 1e-12 BER

% Targeted BER
target_ber = 1e-12;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
Preq = x_values_ook;

% Initial link margin array
LinkMargins_12 = zeros(size(satDistances));

for i = 1:length(satDistances)

    
    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate link margin
    LinkMargins_12(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss - Preq;

end


% Targeted BER
target_ber = app.BitErrorRateBER;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
Preq = x_values_ook;
% Link Margin of the Specific Point @ 1000km
LinkMargin_1000km = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - fspl(app.SatelliteDistance*1e3,app.Wavelength)-Preq;
app.LinkMargindBEditField.Value = LinkMargin_1000km;


% ---------IV.2 Link Margin versus Satellite Distance (1e-10 BER) -----------

% Set required receiver power for 1e-10 BER
% Targeted BER
target_ber = 1e-10;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
Preq = x_values_ook;

% Initial link margin array
LinkMargins_10 = zeros(size(satDistances));

for i = 1:length(satDistances)


    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate link margin
    LinkMargins_10(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss - Preq;

end


% ---------IV.3 Link Margin versus Satellite Distance (1e-8 BER) -----------

% Set required receiver power for 1e-8 BER
% Targeted BER
target_ber = 1e-8;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
Preq = x_values_ook;

% Initial link margin array
LinkMargins_8 = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate link margin
    LinkMargins_8(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss - Preq;

end


% ---------IV.5 Link Margin versus Satellite Distance (1e-6 BER) -----------

% Set required receiver power for 1e-6 BER
% Targeted BER
target_ber = 1e-6;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
Preq = x_values_ook;

% Initial link margin array
LinkMargins_6 = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate link margin
    LinkMargins_6(i) = Ptx + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss - Preq;

end


% Plot link margin versus satellite distance
cla(app.LM_vs_Distance); % 
plot(app.LM_vs_Distance, satDistances, LinkMargins_12, '-o', 'LineWidth', 2,'Color','#7E649E','DisplayName','BER=1e-12 (Preq=-35.8dBm)');
hold(app.LM_vs_Distance, 'on');
plot(app.LM_vs_Distance, satDistances, LinkMargins_10, '-s', 'LineWidth', 2,'Color','#31859B','DisplayName','BER=1e-10 (Preq=-37.1dBm)');
plot(app.LM_vs_Distance, satDistances, LinkMargins_8, '-x', 'LineWidth', 2,'Color','#789440','DisplayName','BER=1e-08 (Preq=-38.5dBm)');
plot(app.LM_vs_Distance, satDistances, LinkMargins_6, '-^', 'LineWidth', 2,'Color','#923931','DisplayName','BER=1e-06 (Preq=-40.3dBm)');

legend(app.LM_vs_Distance, 'show');

grid (app.LM_vs_Distance, 'on');
hold (app.LM_vs_Distance, 'off');


%----------------------------------------------------------------------------------------------------------------------------------------------------------

Ep = 1.98646*1e-25/app.Wavelength;
LinkMargin_1000km = 10.545189420757431;

% -------- I. Received Power versus Satellite Distance (Changing Link Margin)-----------------------------
% Under the condition of:

% ---------I.1 Received Power versus Satellite Distance (1550 nm) -----------

% Targeted BER
target_ber = 1e-12;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
x_values_ook_W = 10^(x_values_ook/10-3);
Nd = x_values_ook_W/(Ep * 10*1e9);

% Initial received power array
Prxs_1550nm = zeros(size(satDistances));
Preq_1550nm = zeros(size(satDistances));
DataRates_1550nm = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_1550nm(i) = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;
    Preq_1550nm(i) = Prxs_1550nm(i)-LinkMargin_1000km;

     % Calculate achievable data rate
    DataRates_1550nm(i) = (1 .* (10.^(Preq_1550nm(i)/10 -3))/(Ep* round(Nd)))/(10^9);
   

end


% Targeted BER
target_ber = app.BitErrorRateBER;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers
x_values_ook_W = 10^(x_values_ook/10-3);
Nd = x_values_ook_W/(Ep * 10*1e9);
Prxs_Point = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - fspl(app.SatelliteDistance*1e3,app.Wavelength);
    Preq_Point = Prxs_Point-LinkMargin_1000km;
app.DataRateGbsEditField.Value = (1 .* (10.^(Preq_Point/10 -3))/(Ep* round(Nd)))/(10^9);


% -------- I.2 Received Power versus Satellite Distance (Changing Link Margin)-----------------------------
% ---------I.1 Received Power versus Satellite Distance (1550 nm) -----------


% Targeted BER
target_ber = 1e-10;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers

x_values_ook_W = 10^(x_values_ook/10-3);
Nd = x_values_ook_W/(Ep * 10*1e9);


% Initial received power array
Prxs_1550nm_6LM = zeros(size(satDistances));
Preq_1550nm_6LM = zeros(size(satDistances));
DataRates_1550nm_6LM = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_1550nm_6LM(i) = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;
    Preq_1550nm_6LM(i) = Prxs_1550nm_6LM(i)-LinkMargin_1000km;

     % Calculate achievable data rate
    DataRates_1550nm_6LM(i) = (1 .* (10.^(Preq_1550nm_6LM(i)/10 -3))/(Ep* round(Nd)))/(10^9);
   

end


% -------- I. Received Power versus Satellite Distance (Changing Link Margin)-----------------------------

% ---------I.1 Received Power versus Satellite Distance (1550 nm) -----------


% Targeted BER
target_ber = 1e-8;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers

x_values_ook_W = 10^(x_values_ook/10-3);
Nd = x_values_ook_W/(Ep * 10*1e9);


% Initial received power array
Prxs_1550nm_3LM = zeros(size(satDistances));
Preq_1550nm_3LM = zeros(size(satDistances));
DataRates_1550nm_3LM = zeros(size(satDistances));

for i = 1:length(satDistances)

    
    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_1550nm_3LM(i) = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;
    Preq_1550nm_3LM(i) = Prxs_1550nm_3LM(i)-LinkMargin_1000km;

     % Calculate achievable data rate
    DataRates_1550nm_3LM(i) = (1 .* (10.^(Preq_1550nm_3LM(i)/10 -3))/(Ep* round(Nd)))/(10^9);
   

end


% -------- I. Received Power versus Satellite Distance (Changing Link Margin)-----------------------------

% ---------I.1 Received Power versus Satellite Distance (1550 nm) -----------


% Targeted BER
target_ber = 1e-6;
target_log_ber = log10(target_ber);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
x_values_ook = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers

x_values_ook_W = 10^(x_values_ook/10-3);
Nd = x_values_ook_W/(Ep * 10*1e9);


% Initial received power array
Prxs_1550nm_12LM = zeros(size(satDistances));
Preq_1550nm_12LM = zeros(size(satDistances));
DataRates_1550nm_12LM = zeros(size(satDistances));

for i = 1:length(satDistances)

    % Calculate transmitter and receiver gain
    txGain = (16/(app.SatATxDivergenceAngle)^2);
    Gtx = 10*log10(txGain); % in dB
    rxGain = (pi*app.SatBApertureDiameter/app.Wavelength)^2;
    Grx = 10*log10(rxGain); % in dB
    % Calculate transmitter and receiver pointing loss in dB
    txPointingLoss = 4.3429*(txGain*(app.SatAPointingError)^2);
    rxPointingLoss = 4.3429*(rxGain*(app.SatBPointingError)^2);

    % Calculate link margin for inter-satellite link in dB
    % Free-space path loss between satellites in dB
    pathLoss = fspl(satDistances(i)*1e3,app.Wavelength);

    % Calculate received power
    Prxs_1550nm_12LM(i) = app.TransmittedPower + 10*log10(app.SatAOpticsEfficiency) + 10*log10(app.SatBOpticsEfficiency) + ...
    Gtx + Grx - txPointingLoss - rxPointingLoss - pathLoss;
    Preq_1550nm_12LM(i) = Prxs_1550nm_12LM(i)-LinkMargin_1000km;

     % Calculate achievable data rate
    DataRates_1550nm_12LM(i) = (1 .* (10.^(Preq_1550nm_12LM(i)/10 -3))/(Ep* round(Nd)))/(10^9);
   

end


cla(app.Drate_vs_Distance); % 
plot(app.Drate_vs_Distance, satDistances, DataRates_1550nm, '-o', 'LineWidth', 2,'Color','#7E649E','DisplayName','BER=1e-12 (Preq=-35.8dBm)');
hold (app.Drate_vs_Distance, 'on');
plot(app.Drate_vs_Distance, satDistances, DataRates_1550nm_6LM, '-x', 'LineWidth', 2,'Color','#789440','DisplayName','BER=1e-10 (Preq=-37.1dBm)');
plot(app.Drate_vs_Distance, satDistances, DataRates_1550nm_3LM, '-s', 'LineWidth', 2,'Color','#31859B','DisplayName','BER=1e-08 (Preq=-38.5dBm)');
plot(app.Drate_vs_Distance, satDistances, DataRates_1550nm_12LM, '-d', 'LineWidth', 2,'Color','#923931','DisplayName','BER=1e-06 (Preq=-40.3dBm)');

legend(app.Drate_vs_Distance, 'show');

%set(app.Drate_vs_Distance, gca, 'YScale', 'log');  % Set the y-axis to logarithmic scale
grid (app.Drate_vs_Distance, "on");
hold (app.Drate_vs_Distance, "off");


%----------------------------------------------------------------------------------------------------------------------------------------------------------


        end

        % Button pushed function: BERCurveFittingButton
        function BERCurveFittingButtonPushed(app, event)

   app.BitErrorRateBER = app.BEREditField.Value;
            
            
            % OOK data point
power_ook = [-50, -49.1, -48.1, -47, -46, -45, -44, -43, -42, -41, -40, -39, -38, -37];
log_ber_ook = [-1.15, -1.35, -1.6, -1.9, -2.15, -2.7, -3, -3.8, -4.55, -5.25, -6.25, -7.6, -8.8, -10];

% 3-rd order curve fitting
p_ook_3th = polyfit(power_ook, log_ber_ook, 3);

% generate curve
fitted_log_ber_ook_3th = polyval(p_ook_3th, power_ook);

% from log scale to linear BER
fitted_ber_ook_3th = 10.^fitted_log_ber_ook_3th;

% from log scale to linear BER
ber_ook = 10.^log_ber_ook;

% plot original data point
semilogy(app.BER_vs_Preq, power_ook, ber_ook, 'bo', 'MarkerFaceColor', 'b'); % OOK Data points
hold (app.BER_vs_Preq, 'on');

% plot 3-rd order fitted curve
semilogy(app.BER_vs_Preq, power_ook, fitted_ber_ook_3th, 'b-'); % OOK 3-rd order fitted curve

grid (app.BER_vs_Preq, 'on');
hold (app.BER_vs_Preq, 'off');


target_log_ber = log10(app.BitErrorRateBER);
% Polynomial coefficients minus target log_ber
p_ook_3th_adjusted = p_ook_3th;
p_ook_3th_adjusted(end) = p_ook_3th_adjusted(end) - target_log_ber;
% Solve for the roots of a polynomial
x_values_ook = roots(p_ook_3th_adjusted);
app.ReqPrxdBmEditField.Value = x_values_ook(imag(x_values_ook) == 0); % Keep only the roots of real numbers


        end

        % Callback function
        function TestButtonPushed(app, event)
             x = linspace(1, 10, 10); % x 从 1 到 10，10 个数据点
    y = 2 * x + app.SatATxDivergenceAngle; % 一次函数

    % 确保绘图在 BERAxes 中
    cla(app.BERAxes); % 清除 BERAxes 上的现有内容
    hold(app.BERAxes, 'on'); % 允许多次绘图
    plot(app.BERAxes, x, y, '-o', 'LineWidth', 2, 'MarkerFaceColor', 'b'); % 绘制曲线

    % 设置坐标轴标签和标题
    xlabel(app.BERAxes, 'x');
    ylabel(app.BERAxes, 'y = 2x + 1');
    title(app.BERAxes, 'Test Plot in BERAxes');
    grid(app.BERAxes, 'on'); % 显示网格
    hold(app.BERAxes, 'off'); % 关闭 hold 模式
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Prx_vs_Distance = uiaxes(app.UIFigure);
            title(app.Prx_vs_Distance, 'Received Power vs Satellite Distance Plot')
            xlabel(app.Prx_vs_Distance, 'Distance between Satellites in km')
            ylabel(app.Prx_vs_Distance, 'Received Power in dBm')
            zlabel(app.Prx_vs_Distance, 'Z')
            app.Drate_vs_Distance = uiaxes(app.UIFigure);
            title(app.Drate_vs_Distance, 'Data Rate vs Satellite Distance Plot')
            xlabel(app.Drate_vs_Distance, 'Distance between Satellites in km')
            ylabel(app.Drate_vs_Distance, 'Data Rate in Gb/s')
            zlabel(app.Drate_vs_Distance, 'Z')
            app.Drate_vs_Distance.YScale = 'log';
            app.LM_vs_Distance = uiaxes(app.UIFigure);
            title(app.LM_vs_Distance, 'Link Margin vs Satellite Distance Plot')
            xlabel(app.LM_vs_Distance, 'Distance between Satellites in km')
            ylabel(app.LM_vs_Distance, 'Link Margin in dB')
            zlabel(app.LM_vs_Distance, 'Z')
            app.BER_vs_Preq = uiaxes(app.UIFigure);
            title(app.BER_vs_Preq, {'BER vs Required Received Power '; 'with Curve Fitting'})
            xlabel(app.BER_vs_Preq, {'Required Received Optical Power '; '(Req. Prx) in dBm'})
            ylabel(app.BER_vs_Preq, 'Bit Error Rate (BER)')
            zlabel(app.BER_vs_Preq, 'Z')
            app.SatAOpticsEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.SatAOpticsEfficiencyEditFieldLabel.Text = 'SatA Optics Efficiency';
            app.SatAOpticsEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatAOpticsEfficiencyEditField.Value = 0.8;
            app.ParametersvsDistancePlotsButton = uibutton(app.UIFigure, 'push');
            app.ParametersvsDistancePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersvsDistancePlotsButtonPushed, true);
            app.ParametersvsDistancePlotsButton.Text = 'Parameters vs Distance Plots';
            app.WavelengthmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthmEditFieldLabel.Text = 'Wavelength (m)';
            app.WavelengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthmEditField.Value = 1.55e-06;
            app.SatAPointingErrradEditFieldLabel = uilabel(app.UIFigure);
            app.SatAPointingErrradEditFieldLabel.Text = 'SatA Pointing Err. (μrad)';
            app.SatAPointingErrradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatAPointingErrradEditField.Value = 1e-06;
            app.SatBOpticsEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.SatBOpticsEfficiencyEditFieldLabel.Text = 'SatB Optics Efficiency';
            app.SatBOpticsEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatBOpticsEfficiencyEditField.Value = 0.8;
            app.SatBApertureDiametermEditFieldLabel = uilabel(app.UIFigure);
            app.SatBApertureDiametermEditFieldLabel.Text = 'SatB Aperture Diameter (m)';
            app.SatBApertureDiametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatBApertureDiametermEditField.Value = 0.08;
            app.SatBPointingErrradEditFieldLabel = uilabel(app.UIFigure);
            app.SatBPointingErrradEditFieldLabel.Text = 'SatB Pointing Err. (μrad)';
            app.SatBPointingErrradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatBPointingErrradEditField.Value = 1e-06;
            app.SatADivAngleradEditFieldLabel = uilabel(app.UIFigure);
            app.SatADivAngleradEditFieldLabel.Text = 'SatA Div. Angle (μrad)';
            app.SatADivAngleradEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatADivAngleradEditField.Value = 1.5e-05;
            app.TransmittedPowerdBmEditFieldLabel = uilabel(app.UIFigure);
            app.TransmittedPowerdBmEditFieldLabel.Text = 'Transmitted Power (dBm)';
            app.TransmittedPowerdBmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmittedPowerdBmEditField.Value = 30;
            app.InterSatelliteOpticalLinkAnalyzerISOLALabel = uilabel(app.UIFigure);
            app.InterSatelliteOpticalLinkAnalyzerISOLALabel.Text = 'Inter-Satellite Optical Link Analyzer (ISOLA)';
            app.MatlabADSCadenceCosimulationPlatformLabel_2 = uilabel(app.UIFigure);
            app.MatlabADSCadenceCosimulationPlatformLabel_2.Text = 'HKUST Optical Wirelss Lab (OWL),   The Hong Kong University of Science and Technology (HKUST)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'HQOWL.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.URL = 'https://hkust.edu.hk/';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'HKUST.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Line.png');
            app.ReceivedPowerdBmEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedPowerdBmEditFieldLabel.Text = 'Received Power (dBm)';
            app.ReceivedPowerdBmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceivedPowerdBmEditField.Value = -13.3842607609215;
            app.LinkMargindBEditFieldLabel = uilabel(app.UIFigure);
            app.LinkMargindBEditFieldLabel.Text = 'Link Margin (dB)';
            app.LinkMargindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkMargindBEditField.Value = 23.6914959263391;
            app.DataRateGbsEditFieldLabel = uilabel(app.UIFigure);
            app.DataRateGbsEditFieldLabel.Text = 'Data Rate (Gb/s)';
            app.DataRateGbsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataRateGbsEditField.Value = 206.355203584964;
            app.SatDistkmEditFieldLabel = uilabel(app.UIFigure);
            app.SatDistkmEditFieldLabel.Text = 'Sat. Dist. (km)';
            app.SatDistkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatDistkmEditField.Value = 600;
            app.BERCurveFittingButton = uibutton(app.UIFigure, 'push');
            app.BERCurveFittingButton.ButtonPushedFcn = createCallbackFcn(app, @BERCurveFittingButtonPushed, true);
            app.BERCurveFittingButton.Text = 'BER Curve Fitting';
            app.RequiredPrxLabel = uilabel(app.UIFigure);
            app.RequiredPrxLabel.Text = 'Req. Prx (dBm)';
            app.ReqPrxdBmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReqPrxdBmEditField.Value = -37.0757566872606;
            app.BEREditFieldLabel = uilabel(app.UIFigure);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.UIFigure, 'numeric');
            app.BEREditField.Value = 1e-10;
            app.TransmitterTXLabel = uilabel(app.UIFigure);
            app.TransmitterTXLabel.Text = 'Transmitter (TX)';
            app.ReceiverRXLabel = uilabel(app.UIFigure);
            app.ReceiverRXLabel.Text = 'Receiver (RX)';
            app.SatelliteTerminalParametersLabel = uilabel(app.UIFigure);
            app.SatelliteTerminalParametersLabel.Text = 'Satellite Terminal Parameters';
            app.LinkParametersLabel = uilabel(app.UIFigure);
            app.LinkParametersLabel.Text = 'Link Parameters';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Line2.png');
            app.Image5_5 = uiimage(app.UIFigure);
            app.Image5_5.ImageSource = fullfile(pathToMLAPP, 'LinksPicture1.png');
            app.ResultsDerivedfromGivenParametersLabel = uilabel(app.UIFigure);
            app.ResultsDerivedfromGivenParametersLabel.Text = 'Results Derived from Given Parameters ';
            app.Image5_9 = uiimage(app.UIFigure);
            app.Image5_9.ImageSource = fullfile(pathToMLAPP, 'Line2.png');
            app.Datasource1Label = uilabel(app.UIFigure);
            app.Datasource1Label.Text = 'Data source: [1]';
            app.Defaultvaluesbasedon2AdjustablebyuserLabel = uilabel(app.UIFigure);
            app.Defaultvaluesbasedon2AdjustablebyuserLabel.Text = 'Default values based on [2]. Adjustable by user.';
            app.Datasource1Label_2 = uilabel(app.UIFigure);
            app.Datasource1Label_2.Text = {'[1] A. Maho et al., “Assessment of the effective performance of DPSK vs. OOK in satellite-based optical communications,” in International Conference on Space Optics — ICSO 2018, Jul. 2019.'; '[2] J. Liang et al, “Link Budget Analysis for Free-Space Optical Satellite Networks,” in 2022 IEEE 23rd International Symposium on a World of Wireless, Mobile and Multimedia Networks (WoWMoM), Jun. 2022.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_App_for_OISL_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end